classdef OrbitSimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        simulateButtonPushed  matlab.ui.control.Button
        SliderTmax            matlab.ui.control.Slider
        SliderTmaxLabel       matlab.ui.control.Label
        SliderV3              matlab.ui.control.Slider
        SliderV3Label         matlab.ui.control.Label
        SliderV2              matlab.ui.control.Slider
        SliderV2Label         matlab.ui.control.Label
        SliderV1              matlab.ui.control.Slider
        SliderV1Label         matlab.ui.control.Label
        SliderT0              matlab.ui.control.Slider
        SliderT0Label         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulateButtonPushed
        function simulateButtonPushedPushed(app, event)
            %% 获取滑块值
            t0_days = app.SliderT0.Value;          % 天
            v1 = app.SliderV1.Value;               % km/s
            v2 = app.SliderV2.Value;               % km/s
            v3 = app.SliderV3.Value;               % km/s
            tmax_days = app.SliderTmax.Value;      % 天

            if tmax_days<=t0_days
                errordlg('不允许的仿真时间和变轨时间','error'); %如果放置时间不够大输出一个错误对话框
                
                return;
            end


            %% 参数和常数
            t0 = t0_days * 86400;
            tmax = tmax_days * 86400;
 
            mu = 398600;          % 地球引力参数 km³/s²
            R_earth = 6371;            % 地球半径 km
            h = 400;                   % 轨道高度 km（近地轨道）
            r0 = R_earth + h;          % 轨道半径 km
            v_circular = sqrt(mu/r0);  % 圆轨道速度

            %% 初始状态
            x0 = r0; y0 = 0; z0 = 0;              % km
            vx0 = 0; vy0 = v_circular; vz0 = 0;   % km/s
 
            %% 轨道微分方程（是a=-mu/r^3,把他处理成xyz三轴方向）
            odefun = @(t, Y) [Y(4); Y(5); Y(6);
                -mu * Y(1) / (norm(Y(1:3))^3);
                -mu * Y(2) / (norm(Y(1:3))^3);
                -mu * Y(3) / (norm(Y(1:3))^3)];
 
            % ODE选项
            options = odeset('MaxStep',1);
            numPoints = 1000;
 
            %% 变轨前轨道仿真
            if t0 > 1
                tspan_before = linspace(0, t0, numPoints);
                [~, Y_before] = ode23(odefun, tspan_before, [x0; y0; z0; vx0; vy0; vz0], options);
            else
                Y_before = [x0, y0, z0, vx0, vy0, vz0];
            end
 
            %% 变轨点计算
            x_t0 = Y_before(end, 1);
            y_t0 = Y_before(end, 2);
            z_t0 = Y_before(end, 3);
            vx_t0 = Y_before(end, 4) + v1;
            vy_t0 = Y_before(end, 5) + v2;
            vz_t0 = Y_before(end, 6) + v3;
 
            %% 变轨后轨道
            tspan_after = linspace(t0, tmax, numPoints);
            [~, Y_after] = ode23(odefun, tspan_after, [x_t0; y_t0; z_t0; vx_t0; vy_t0; vz_t0], options);
 
            %% 绘图
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
 
            % 绘制地球
            [X_e, Y_e, Z_e] = sphere(30);
            surf(app.UIAxes, X_e*R_earth, Y_e*R_earth, Z_e*R_earth, ...
                'FaceColor', [0.2 0.5 0.8], 'EdgeColor', 'none', ...
                'FaceAlpha', 0.5, 'DisplayName', '地球');
 
            % 变轨前轨道（蓝色）
            if size(Y_before, 1) > 1
                plot3(app.UIAxes, Y_before(:,1), Y_before(:,2), Y_before(:,3), ...
                    'b-', 'LineWidth', 2, 'DisplayName', '变轨前轨道');
            end
 
            % 变轨后轨道（红色）
            plot3(app.UIAxes, Y_after(:,1), Y_after(:,2), Y_after(:,3), ...
                'r-', 'LineWidth', 2, 'DisplayName', '变轨后轨道');
 
            % 标记点
            plot3(app.UIAxes, x0, y0, z0, 'bs', 'MarkerSize', 12, ...
                'MarkerFaceColor', 'b', 'LineWidth', 2, 'DisplayName', '起点');
            plot3(app.UIAxes, x_t0, y_t0, z_t0, 'gp', 'MarkerSize', 18, ...
                'MarkerFaceColor', 'g', 'LineWidth', 2, 'DisplayName', '机动点');
            plot3(app.UIAxes, Y_after(end,1), Y_after(end,2), Y_after(end,3), ...
                'r^', 'MarkerSize', 12, 'MarkerFaceColor', 'r', ...
                'LineWidth', 2, 'DisplayName', '终点');
 
            % 参考圆轨道
            theta = linspace(0, 2*pi, 200);
            plot3(app.UIAxes, r0*cos(theta), r0*sin(theta), zeros(size(theta)), ...
                'k:', 'LineWidth', 1, 'DisplayName', '初始圆轨道');

            % 箭头绘制
            startpoint=[x_t0,y_t0,z_t0];
            finishpoint1=[0,0,0];
            arrowvector1=finishpoint1-startpoint;
            arrowvector2=[1000*v1,1000*v2,1000*v3];
            quiver3(app.UIAxes,startpoint(1), startpoint(2), startpoint(3), arrowvector1(1), arrowvector1(2), arrowvector1(3),...
                'Color', 'yellow', 'LineWidth', 2, 'AutoScale', 'on',...
                'DisplayName',"机动点指向地心");
            quiver3(app.UIAxes,startpoint(1), startpoint(2), startpoint(3), arrowvector2(1), arrowvector2(2), arrowvector2(3),...
                'Color', 'black', 'LineWidth', 2, 'AutoScale', 'on',...
                "DisplayName","速度改变的矢量方向（1000倍大小）");
            
            
            xlabel(app.UIAxes, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.UIAxes, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            zlabel(app.UIAxes, 'Z (km)', 'FontSize', 12, 'FontWeight', 'bold');
            
            dv_total = sqrt(v1^2 + v2^2 + v3^2);
            title(app.UIAxes, sprintf('地球轨道机动 | t_0=%.2f天 | Δv=%.3f km/s', ...
                t0_days, dv_total), 'FontSize', 14);
            
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            legend(app.UIAxes, 'Location', 'bestoutside', 'FontSize', 10);
            view(app.UIAxes, 3);
            hold(app.UIAxes, 'off');
 
            % 自动调整坐标范围
            allX = [Y_before(:,1); Y_after(:,1)];
            allY = [Y_before(:,2); Y_after(:,2)];
            maxRange = max([max(abs(allX)), max(abs(allY)), r0]) * 1.2;
            xlim(app.UIAxes, [-maxRange, maxRange]);
            ylim(app.UIAxes, [-maxRange, maxRange]);
 

        end

        % Value changed function: SliderT0, SliderTmax, SliderV1, 
        % ...and 2 other components
        function sliderValueChanged(app, event)
            app.SliderT0Label.Text = sprintf('机动时间 t₀: %.2f 天', app.SliderT0.Value);
            app.SliderV1Label.Text = sprintf('Δvₓ: %.3f km/s', app.SliderV1.Value);
            app.SliderV2Label.Text = sprintf('Δvᵧ: %.3f km/s', app.SliderV2.Value);
            app.SliderV3Label.Text = sprintf('Δvᵤ: %.3f km/s', app.SliderV3.Value);
            app.SliderTmaxLabel.Text = sprintf('仿真时长: %.2f 天', app.SliderTmax.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderT0Label = uilabel(app.UIFigure);
            app.SliderT0Label.Text = 'SliderT0';
            app.SliderT0 = uislider(app.UIFigure);
            app.SliderT0.Limits = [0 0.5];
            app.SliderT0.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV1Label = uilabel(app.UIFigure);
            app.SliderV1Label.Text = 'SliderV1';
            app.SliderV1 = uislider(app.UIFigure);
            app.SliderV1.Limits = [0 10];
            app.SliderV1.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV2Label = uilabel(app.UIFigure);
            app.SliderV2Label.Text = 'SliderV2';
            app.SliderV2 = uislider(app.UIFigure);
            app.SliderV2.Limits = [0 10];
            app.SliderV2.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV3Label = uilabel(app.UIFigure);
            app.SliderV3Label.Text = 'SliderV3';
            app.SliderV3 = uislider(app.UIFigure);
            app.SliderV3.Limits = [0 10];
            app.SliderV3.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderTmaxLabel = uilabel(app.UIFigure);
            app.SliderTmaxLabel.Text = 'SliderTmax';
            app.SliderTmax = uislider(app.UIFigure);
            app.SliderTmax.Limits = [0 0.5];
            app.SliderTmax.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.simulateButtonPushed = uibutton(app.UIFigure, 'push');
            app.simulateButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushedPushed, true);
            app.simulateButtonPushed.Text = 'simulateButtonPushed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitSimulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end